classdef sub_segment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DualSubcorticalSegmentToolkitUIFigure  matlab.ui.Figure
        DataInputPanel           matlab.ui.container.Panel
        TextArea                 matlab.ui.control.TextArea
        Btn_input                matlab.ui.control.Button
        DataPathLabel            matlab.ui.control.Label
        Edt_input                matlab.ui.control.EditField
        MasksPanel               matlab.ui.container.Panel
        CortexEditFieldLabel     matlab.ui.control.Label
        Edt_mask_cortex          matlab.ui.control.EditField
        SubcorEditFieldLabel     matlab.ui.control.Label
        Edt_mask_sub             matlab.ui.control.EditField
        Btn_mask_cortex          matlab.ui.control.Button
        Btn_mask_sub             matlab.ui.control.Button
        InitialSegmentPanel      matlab.ui.container.Panel
        BtnGrp_seg               matlab.ui.container.ButtonGroup
        Btn_seg_number           matlab.ui.control.RadioButton
        Btn_seg_cortex           matlab.ui.control.RadioButton
        Btn_seg_sub              matlab.ui.control.RadioButton
        Btn_seg_cortex_browse    matlab.ui.control.Button
        Edt_seg_sub              matlab.ui.control.EditField
        Btn_seg_sub_browse       matlab.ui.control.Button
        Edt_seg_number           matlab.ui.control.NumericEditField
        Btn_seg_number_est       matlab.ui.control.Button
        Edt_seg_cortex           matlab.ui.control.EditField
        SegmentThresholdLabel    matlab.ui.control.Label
        Edt_seg_thre             matlab.ui.control.NumericEditField
        OutputPanel              matlab.ui.container.Panel
        OutputDirEditFieldLabel  matlab.ui.control.Label
        Edt_output               matlab.ui.control.EditField
        Btn_output_browse        matlab.ui.control.Button
        Btn_run                  matlab.ui.control.Button
        SegmentStrategyPanel     matlab.ui.container.Panel
        BtnGrp_Seg_Strategy      matlab.ui.container.ButtonGroup
        Btn_segstra_group        matlab.ui.control.RadioButton
        Btn_segstra_indi         matlab.ui.control.RadioButton
    end

    methods (Access = private)

        % Button pushed function: Btn_input
        function Btn_inputPushed(app, event)
            Dir_input = uigetdir('','please select the document of input rs-fMRI data');
            app.Edt_input.Value = Dir_input;
            niis = dir([Dir_input,filesep,'*',filesep,'*.nii']);
            app.TextArea.Value = {''};
            for i = 1:length(niis)
                app.TextArea.Value = cat(1,app.TextArea.Value,[niis(i).folder,filesep,niis(i).name]);
            end
            app.TextArea.Value(1) = [];
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: Btn_mask_cortex
        function Btn_mask_cortexButtonPushed(app, event)
            [mask_cortex_name,mask_cortex_path] = uigetfile('*.nii','please select the cortex mask');
            app.Edt_mask_cortex.Value = [mask_cortex_path,filesep,mask_cortex_name];
        end

        % Button pushed function: Btn_mask_sub
        function Btn_mask_subButtonPushed(app, event)
            [mask_sub_name,mask_sub_path] = uigetfile('*.nii','please select the subcortical mask');
            app.Edt_mask_sub.Value = [mask_sub_path,filesep,mask_sub_name];
        end

        % Selection changed function: BtnGrp_seg
        function BtnGrp_segSelectionChanged(app, event)
            selectedButton = app.BtnGrp_seg.SelectedObject;
            if(selectedButton == app.Btn_seg_number)
                app.Edt_seg_number.Enable = 'on';
                app.Btn_seg_number_est.Enable = 'on';
                app.Edt_seg_cortex.Enable = 'off';
                app.Btn_seg_cortex_browse.Enable = 'off';
                app.Edt_seg_sub.Enable = 'off';
                app.Btn_seg_sub_browse.Enable = 'off';
            elseif(selectedButton == app.Btn_seg_cortex)
                app.Edt_seg_number.Enable = 'off';
                app.Btn_seg_number_est.Enable = 'off';
                app.Edt_seg_cortex.Enable = 'on';
                app.Btn_seg_cortex_browse.Enable = 'on';
                app.Edt_seg_sub.Enable = 'off';
                app.Btn_seg_sub_browse.Enable = 'off';
            elseif(selectedButton == app.Btn_seg_sub)
                app.Edt_seg_number.Enable = 'off';
                app.Btn_seg_number_est.Enable = 'off';
                app.Edt_seg_cortex.Enable = 'off';
                app.Btn_seg_cortex_browse.Enable = 'off';
                app.Edt_seg_sub.Enable = 'on';
                app.Btn_seg_sub_browse.Enable = 'on';
            end
        end

        % Button pushed function: Btn_seg_number_est
        function Btn_seg_number_estButtonPushed(app, event)
            nii_files = app.TextArea.Value;
            if(isempty(nii_files))
                error('the input is none');
            end
            mask_sub_path = app.Edt_mask_sub.Value;
            if(isempty(mask_sub_path))
                error('please define the subcortical mask');
            end
            mask_cortex_path = app.Edt_mask_cortex.Value;
            if(isempty(mask_cortex_path))
                error('please define the cortex mask');
            end
            fun_data = {};
            for i = 1:length(nii_files)
                if(isempty(nii_files{i}))
                    continue;
                end
                nii_file_path = nii_files{i};
                data_tmp = single(y_Read(nii_file_path)); % to reduce the size
                fun_data = cat(1,fun_data,data_tmp);
            end
            mask_sub = y_Read(mask_sub_path);
            mask_cortex = y_Read(mask_cortex_path);
            disp('the estimation will take a long time!! please wait');
            seg_num = F_determine_seg_num(fun_data,mask_sub,mask_cortex);
            disp('estimation done');
            app.Edt_seg_number.Value = seg_num;
        end

        % Button pushed function: Btn_seg_cortex_browse
        function Btn_seg_cortex_browseButtonPushed(app, event)
            [seg_cortex_name,seg_cortex_path] = uigetfile('*.nii','please select the initial segment of cortex');
            app.Edt_seg_cortex.Value = [seg_cortex_path,filesep,seg_cortex_name];
        end

        % Button pushed function: Btn_seg_sub_browse
        function Btn_seg_sub_browseButtonPushed(app, event)
            [seg_sub_name,seg_sub_path] = uigetfile('*.nii','please select the initial segment of subcortical region');
            app.Edt_seg_sub.Value = [seg_sub_path,filesep,seg_sub_name];
        end

        % Button pushed function: Btn_output_browse
        function Btn_output_browsePushed(app, event)
            output_path = uigetdir('','please select the dir to store the segmented cortex and subcortical files');
            app.Edt_output.Value = output_path;
        end

        % Button pushed function: Btn_run
        function Btn_runButtonPushed(app, event)
            disp('segment setup......');
            app.Btn_run.Enable = 'off';
            
            output_path = app.Edt_output.Value;
            if(isempty(output_path))
                error('the output is empty');
            end
            
            mask_cortex_path = app.Edt_mask_cortex.Value;
            mask_sub_path = app.Edt_mask_sub.Value;
            if(isempty(mask_cortex_path) || isempty(mask_sub_path))
                error('please input the masks');
            end
            [mask_cortex,head_cortex] = y_Read(mask_cortex_path);
            [mask_sub,head_sub] = y_Read(mask_sub_path);
            
            if(app.BtnGrp_seg.SelectedObject == app.Btn_seg_number)
                seg_initial = app.Edt_seg_number.Value;
            elseif(app.BtnGrp_seg.SelectedObject == app.Btn_seg_cortex)
                seg_initial = y_Read(app.Edt_seg_cortex.Value);
            elseif(app.BtnGrp_seg.SelectedObject == app.Btn_seg_sub)
                seg_initial = y_Read(app.Edt_seg_sub.Value);
            end
            
            fun_data_path = app.TextArea.Value;
            if(isempty(fun_data_path))
                error('the input fMRI data is empty');
            end
            fun_data = {};
            for i = 1:length(fun_data_path)
                fun_data_path_tmp = fun_data_path{i};
                if(isempty(fun_data_path_tmp))
                    continue;
                end
                fun_data_tmp = single(y_Read(fun_data_path_tmp)); % to reduce the size
                fun_data = cat(1,fun_data,fun_data_tmp);
            end
            
            if(app.BtnGrp_Seg_Strategy.SelectedObject == app.Btn_segstra_group)
                seg_strategy = 1;
            elseif(app.BtnGrp_Seg_Strategy.SelectedObject == app.Btn_segstra_indi)
                seg_strategy = 2;
            end
            
            seg_thre = app.Edt_seg_thre.Value;
            
            disp('segmenting......');
            [seg_cortex,seg_sub] = F_dual_segment(fun_data,mask_cortex,mask_sub,...
                seg_initial,seg_thre,seg_strategy);
            
            disp('writing results......');
            head_cortex.dt(1) = 8;
            head_sub.dt(1) = 8;
            if(iscell(seg_cortex))
                dlmwrite([output_path,filesep,'sub_list.txt'],fun_data_path);
                for i = 1:length(seg_cortex)
                    y_Write(seg_cortex{i},head_cortex,[output_path,filesep,...
                        'segment_cortex_sub',num2str(i),'.nii']);
                    y_Write(seg_sub{i},head_sub,[output_path,filesep,...
                        'segment_sub_sub',num2str(i),'.nii']);
                end
            else
                y_Write(seg_cortex,head_cortex,[output_path,filesep,'segment_cortex.nii']);
                y_Write(seg_sub,head_sub,[output_path,filesep,'segment_sub.nii']);
            end
            disp('done');
            app.Btn_run.Enable = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DualSubcorticalSegmentToolkitUIFigure = uifigure;
            app.DualSubcorticalSegmentToolkitUIFigure.Name = 'Dual Subcortical Segment Toolkit';
            app.DataInputPanel = uipanel(app.DualSubcorticalSegmentToolkitUIFigure);
            app.DataInputPanel.Title = 'Data Input';
            app.TextArea = uitextarea(app.DataInputPanel);
            app.Btn_input = uibutton(app.DataInputPanel, 'push');
            app.Btn_input.ButtonPushedFcn = createCallbackFcn(app, @Btn_inputPushed, true);
            app.Btn_input.Text = 'Browse';
            app.DataPathLabel = uilabel(app.DataInputPanel);
            app.DataPathLabel.Text = 'Data Path:';
            app.Edt_input = uieditfield(app.DataInputPanel, 'text');
            app.MasksPanel = uipanel(app.DualSubcorticalSegmentToolkitUIFigure);
            app.MasksPanel.Title = 'Masks';
            app.CortexEditFieldLabel = uilabel(app.MasksPanel);
            app.CortexEditFieldLabel.Text = 'Cortex:';
            app.Edt_mask_cortex = uieditfield(app.MasksPanel, 'text');
            app.SubcorEditFieldLabel = uilabel(app.MasksPanel);
            app.SubcorEditFieldLabel.Text = 'Subcor:';
            app.Edt_mask_sub = uieditfield(app.MasksPanel, 'text');
            app.Btn_mask_cortex = uibutton(app.MasksPanel, 'push');
            app.Btn_mask_cortex.ButtonPushedFcn = createCallbackFcn(app, @Btn_mask_cortexButtonPushed, true);
            app.Btn_mask_cortex.Text = 'Browse';
            app.Btn_mask_sub = uibutton(app.MasksPanel, 'push');
            app.Btn_mask_sub.ButtonPushedFcn = createCallbackFcn(app, @Btn_mask_subButtonPushed, true);
            app.Btn_mask_sub.Text = 'Browse';
            app.InitialSegmentPanel = uipanel(app.DualSubcorticalSegmentToolkitUIFigure);
            app.InitialSegmentPanel.Title = 'Initial Segment';
            app.BtnGrp_seg = uibuttongroup(app.InitialSegmentPanel);
            app.BtnGrp_seg.SelectionChangedFcn = createCallbackFcn(app, @BtnGrp_segSelectionChanged, true);
            app.Btn_seg_number = uiradiobutton(app.BtnGrp_seg);
            app.Btn_seg_number.Text = 'Number of segment::';
            app.Btn_seg_number.Value = true;
            app.Btn_seg_cortex = uiradiobutton(app.BtnGrp_seg);
            app.Btn_seg_cortex.Text = 'Cortex Segment:';
            app.Btn_seg_sub = uiradiobutton(app.BtnGrp_seg);
            app.Btn_seg_sub.Text = 'Subcor Segment:';
            app.Btn_seg_cortex_browse = uibutton(app.InitialSegmentPanel, 'push');
            app.Btn_seg_cortex_browse.ButtonPushedFcn = createCallbackFcn(app, @Btn_seg_cortex_browseButtonPushed, true);
            app.Btn_seg_cortex_browse.Enable = 'off';
            app.Btn_seg_cortex_browse.Text = 'Browse';
            app.Edt_seg_sub = uieditfield(app.InitialSegmentPanel, 'text');
            app.Edt_seg_sub.Enable = 'off';
            app.Btn_seg_sub_browse = uibutton(app.InitialSegmentPanel, 'push');
            app.Btn_seg_sub_browse.ButtonPushedFcn = createCallbackFcn(app, @Btn_seg_sub_browseButtonPushed, true);
            app.Btn_seg_sub_browse.Enable = 'off';
            app.Btn_seg_sub_browse.Text = 'Browse';
            app.Edt_seg_number = uieditfield(app.InitialSegmentPanel, 'numeric');
            app.Edt_seg_number.Limits = [2 Inf];
            app.Edt_seg_number.RoundFractionalValues = 'on';
            app.Edt_seg_number.Value = 6;
            app.Btn_seg_number_est = uibutton(app.InitialSegmentPanel, 'push');
            app.Btn_seg_number_est.ButtonPushedFcn = createCallbackFcn(app, @Btn_seg_number_estButtonPushed, true);
            app.Btn_seg_number_est.Text = 'Estimate';
            app.Edt_seg_cortex = uieditfield(app.InitialSegmentPanel, 'text');
            app.Edt_seg_cortex.Enable = 'off';
            app.SegmentThresholdLabel = uilabel(app.InitialSegmentPanel);
            app.SegmentThresholdLabel.Text = 'Segment Threshold:';
            app.Edt_seg_thre = uieditfield(app.InitialSegmentPanel, 'numeric');
            app.Edt_seg_thre.Limits = [0 1];
            app.Edt_seg_thre.Value = 0.95;
            app.OutputPanel = uipanel(app.DualSubcorticalSegmentToolkitUIFigure);
            app.OutputPanel.Title = 'Output';
            app.OutputDirEditFieldLabel = uilabel(app.OutputPanel);
            app.OutputDirEditFieldLabel.Text = 'Output Dir:';
            app.Edt_output = uieditfield(app.OutputPanel, 'text');
            app.Btn_output_browse = uibutton(app.OutputPanel, 'push');
            app.Btn_output_browse.ButtonPushedFcn = createCallbackFcn(app, @Btn_output_browsePushed, true);
            app.Btn_output_browse.Text = 'Browse';
            app.Btn_run = uibutton(app.OutputPanel, 'push');
            app.Btn_run.ButtonPushedFcn = createCallbackFcn(app, @Btn_runButtonPushed, true);
            app.Btn_run.Text = 'Run';
            app.SegmentStrategyPanel = uipanel(app.DualSubcorticalSegmentToolkitUIFigure);
            app.SegmentStrategyPanel.Title = 'Segment Strategy';
            app.BtnGrp_Seg_Strategy = uibuttongroup(app.SegmentStrategyPanel);
            app.Btn_segstra_group = uiradiobutton(app.BtnGrp_Seg_Strategy);
            app.Btn_segstra_group.Text = 'Group';
            app.Btn_segstra_group.Value = true;
            app.Btn_segstra_indi = uiradiobutton(app.BtnGrp_Seg_Strategy);
            app.Btn_segstra_indi.Text = 'Individual';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sub_segment

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DualSubcorticalSegmentToolkitUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DualSubcorticalSegmentToolkitUIFigure)
        end
    end
end